classdef FluxGate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FluxgateUIFigure              matlab.ui.Figure
        VoltageValueVEditFieldLabel   matlab.ui.control.Label
        VoltageValueVEditField        matlab.ui.control.NumericEditField
        CurrentValuemAEditFieldLabel  matlab.ui.control.Label
        CurrentValuemAEditField       matlab.ui.control.NumericEditField
        WorkingStatusEditFieldLabel   matlab.ui.control.Label
        WorkingStatusEditField        matlab.ui.control.EditField
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        ConnectionStatusLabel         matlab.ui.control.Label
        ConnectionLamp                matlab.ui.control.Lamp
        ConnectButton                 matlab.ui.control.Button
        LeaveButton                   matlab.ui.control.Button
        ConnectionStatusField         matlab.ui.control.EditField
        FluxgateSensorInterfaceLabel  matlab.ui.control.Label
        VoltageGraph                  matlab.ui.control.UIAxes
        CurrentGraph                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        
        arduinoObj;
        
        VMatris;
        
        StartStop;
        Working_Status = 0;
        Connection_Status = 0;
        Green_Color = [0 1 0];
        Red_Color = [1 0 0];
        
        Axis;
        
        CurrentValue;
        VoltageValue;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartStop = 0;
            
            % init and set up axes
            app.Axis = gca;
            app.Axis.YGrid = 'on';
            
                        
            %app.a=arduino('com3','uno');
            app.ConnectionLamp.Color = app.Red_Color;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if app.StartStop == 0 && app.Connection_Status == 1
                
                app.StartStop = 1;
                
                startTime = second(datetime('now'));
                app.CurrentValue = animatedline(app.CurrentGraph);
                app.VoltageValue = animatedline(app.VoltageGraph);
                
                while app.StartStop == 1
                    
                    dat = readVoltage(app.arduinoObj,'A0');
                
                    V_off = 0.11;     %
                    mr = 27;           %
                    N = 15;            %
                    d = 2*10^-3;       %
                    S = 1.3;           %
                    MPL = pi*(1.75-0.95)/log(17.5/9.5);
                
                    B = 0.4*pi*mr*N/(MPL+mr*d*10);  %G
                    V = dat-V_off;                  %V
                
                    if V >= -75*10^-3 && V <= 75*10^-3
                        V = 0;
                    end
         
                    I = (V*(9.63/213)*10^3)/(S*B);
                
                    %Get current time
                    t = second(datetime('now')) - startTime;
                    %Add points to animation
                    addpoints(app.CurrentValue,datenum(t),I);
                    %Update Axes
                    app.Axis.XLim = datenum([t-seconds(5) t]);
                    datetick('x','keeplimits');
                
                    %Add points to animation
                    addpoints(app.VoltageValue,datenum(t),V);
                    %Update Axes
                    app.Axis.XLim = datenum([t-seconds(5) t]);
                    datetick('x','keeplimits');
                
                    drawnow
                    
                    app.CurrentValuemAEditField.Value = I*10^3;
                    app.VoltageValueVEditField.Value = V;
                    
                end
            elseif app.StartStop == 0 && app.Connection_Status == 0
                app.ConnectionStatusField.Value =  'Cannot Connect';
                app.WorkingStatusEditField.Value = 'Program cannot start.';
                pause(1);
                app.ConnectionStatusField.Value =  '';
                app.WorkingStatusEditField.Value = '';
            
%             app.h1 = animatedline(app.CurrentGraph);
%             app.h2 = animatedline(app.VoltageGraph);
%             
%             ax = gca;
%             ax.YGrid = 'on';
%             
%             app.stop = false;
%             startTime = second(datetime('now'));
%             
%             pause(1)
%             while ~app.stop
%                 dat = readVoltage(app.a,'A0');
%                 
%                 V_off = 0.08;      %
%                 mr = 27;           %
%                 N = 15;            %
%                 d = 2*10^-3;       %
%                 S = 1.3;           %
%                 MPL = pi*(1.75-0.95)/log(17.5/9.5);
%                 
%                 B = 0.4*pi*mr*N/(MPL+mr*d*10);  %G
%                 V = dat-V_off;                  %V
%                 
%                 if V >= -50*10^-3 && V <= 50*10^-3
%                     V = 0;
%                 end
%          
%                 I = (V*(9.63/213)*10^3)/(S*B);
%                 
%                 %Get current time
%                 t = second(datetime('now')) - startTime;
%                 %Add points to animation
%                 addpoints(app.h1,datenum(t),I);
%                 %Update Axes
%                 ax.XLim = datenum([t-seconds(15) t]);
%                 datetick('x','keeplimits');
%                 
%                 %Add points to animation
%                 addpoints(app.h2,datenum(t),V);
%                 %Update Axes
%                 ax.XLim = datenum([t-seconds(15) t]);
%                 datetick('x','keeplimits');
%                 
%                 drawnow
%                 
%                 app.stop = app.done;
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.StartStop == 1 && app.Connection_Status == 1
                
                app.StartStop = 0;
                
                app.WorkingStatusEditField.Value = 'Program is stoped.';
                pause(1);
                app.WorkingStatusEditField.Value = '';
                clear figure;
                
            elseif app.StartStop == 0 && app.Connection_Status == 1
                
                app.StartStop = 0;
                app.ConnectionStatusField.Value = 'Connected';
                app.WorkingStatusEditField.Value = 'Program is not working!';
                pause(1);
                app.ConnectionStatusField.Value =  '';
                app.WorkingStatusEditField.Value = '';
                
            elseif app.StartStop == 0 && app.Connection_Status == 0
                
                app.StartStop = 0;
                app.ConnectionStatusField.Value =  'No Connection';
                app.WorkingStatusEditField.Value = 'Program is not working!';
                pause(1);
                app.ConnectionStatusField.Value =  '';
                app.WorkingStatusEditField.Value = '';
                
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
                
            if app.StartStop == 0 && app.Connection_Status == 0
                
                clc;
                clear arduinoObj;
                
                app.arduinoObj = arduino('COM3','uno');
                app.ConnectionLamp.Color = app.Green_Color;
                app.Connection_Status = 1;
                
            elseif app.Connection_Status == 1 && app.StartStop == 0
                
                app.ConnectionLamp.Color = app.Green_Color;
                app.Connection_Status = 1;
                app.ConnectionStatusField.Value = 'Already Connected';
                pause(1)
                app.ConnectionStatusField.Value = '';
                
            elseif app.Connection_Status == 1 && app.StartStop == 1
                
                app.ConnectionStatusField.Value = 'Connection Available';
                app.WorkingStatusEditField.Value = 'Program is working.';
                app.ConnectionLamp.Color = app.Green_Color;
            end
        end

        % Button pushed function: LeaveButton
        function LeaveButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.Connection_Status == 1 && app.StartStop == 0
                clc;
                clear arduinoObj;
                pause(2)
                app.ConnectionLamp.Color = app.Red_Color;
                app.Connection_Status = 0;
                
            elseif app.Connection_Status == 0 && app.StartStop == 0
                clc;
                clear arduinoObj;              
                app.ConnectionLamp.Color = app.Red_Color;
                app.Connection_Status = 0;
                app.ConnectionStatusField.Value = 'Not connected.';
                pause(1);
                app.ConnectionStatusField.Value = ' ';
                
            elseif app.Connection_Status == 0 && app.StartStop == 1
                clc;
                clear arduinoObj;               
                app.Connection_Status = 1;
                app.ConnectionStatusField.Value = '';
                app.ConnectionStatusField.Value = 'Stop the program first!';
            end
        end

        % Close request function: FluxgateUIFigure
        function FluxgateUIFigureCloseRequest(app, event)
            clc;
            clear arduinoObj;
            app.LeaveButton();
            app.StopButton();
            
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FluxgateUIFigure = uifigure('Visible', 'off');
            app.FluxgateUIFigure.Name = 'Fluxgate';
            app.FluxgateUIFigure.CloseRequestFcn = createCallbackFcn(app, @FluxgateUIFigureCloseRequest, true);
            app.CurrentGraph = uiaxes(app.FluxgateUIFigure);
            title(app.CurrentGraph, 'Primary Current')
            xlabel(app.CurrentGraph, 'Time (s)')
            ylabel(app.CurrentGraph, 'Current (A)')
            zlabel(app.CurrentGraph, 'Z')
            app.VoltageGraph = uiaxes(app.FluxgateUIFigure);
            title(app.VoltageGraph, 'Hall Voltage Output')
            xlabel(app.VoltageGraph, 'Time (s)')
            ylabel(app.VoltageGraph, 'V_h (V)')
            zlabel(app.VoltageGraph, 'Z')
            app.FluxgateSensorInterfaceLabel = uilabel(app.FluxgateUIFigure);
            app.FluxgateSensorInterfaceLabel.Text = 'Fluxgate Sensor Interface';
            app.ConnectionStatusField = uieditfield(app.FluxgateUIFigure, 'text');
            app.LeaveButton = uibutton(app.FluxgateUIFigure, 'push');
            app.LeaveButton.ButtonPushedFcn = createCallbackFcn(app, @LeaveButtonPushed, true);
            app.LeaveButton.Text = 'Leave';
            app.ConnectButton = uibutton(app.FluxgateUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLamp = uilamp(app.FluxgateUIFigure);
            app.ConnectionStatusLabel = uilabel(app.FluxgateUIFigure);
            app.ConnectionStatusLabel.Text = 'Connection Status';
            app.StopButton = uibutton(app.FluxgateUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.FluxgateUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.WorkingStatusEditField = uieditfield(app.FluxgateUIFigure, 'text');
            app.WorkingStatusEditFieldLabel = uilabel(app.FluxgateUIFigure);
            app.WorkingStatusEditFieldLabel.Text = 'Working Status';
            app.CurrentValuemAEditField = uieditfield(app.FluxgateUIFigure, 'numeric');
            app.CurrentValuemAEditFieldLabel = uilabel(app.FluxgateUIFigure);
            app.CurrentValuemAEditFieldLabel.Text = 'Current Value (mA)';
            app.VoltageValueVEditField = uieditfield(app.FluxgateUIFigure, 'numeric');
            app.VoltageValueVEditFieldLabel = uilabel(app.FluxgateUIFigure);
            app.VoltageValueVEditFieldLabel.Text = ' Voltage Value (V)';
            % Show the figure after all components are created
            app.FluxgateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluxGate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FluxgateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FluxgateUIFigure)
        end
    end
end